classdef CoreAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FullDirectoryPanel              matlab.ui.container.Panel
        AxesRatioCheckBox               matlab.ui.control.CheckBox
        CurvatureCheckBox               matlab.ui.control.CheckBox
        AnglesCheckBox                  matlab.ui.control.CheckBox
        IncludecalculationsLabel        matlab.ui.control.Label
        SegmentsNumberEditFieldFolder   matlab.ui.control.NumericEditField
        SegmentsNumberEditField_2Label  matlab.ui.control.Label
        SegmentsLengthEditFieldFolder   matlab.ui.control.NumericEditField
        SegmentsLengthEditField_2Label  matlab.ui.control.Label
        MeasurePlatformAnglesButtonFolder  matlab.ui.control.Button
        SingleFilePanel                 matlab.ui.container.Panel
        MeasureandDisplayPlatformAnglesButton  matlab.ui.control.Button
        PlatformIDEditField             matlab.ui.control.NumericEditField
        PlatformIDEditField_2Label      matlab.ui.control.Label
        SegmentsNumberEditField         matlab.ui.control.NumericEditField
        SegmentsNumberEditFieldLabel    matlab.ui.control.Label
        SegmentsLengthEditField         matlab.ui.control.NumericEditField
        SegmentsLengthEditFieldLabel    matlab.ui.control.Label
        BlanksIDsEditField              matlab.ui.control.EditField
        BlanksIDsEditFieldLabel         matlab.ui.control.Label
        MeasurePlatformAnglesButton     matlab.ui.control.Button
        SPStableCheckBox                matlab.ui.control.CheckBox
        ConvertScarsQinsButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertScarsQinsButton
        function ConvertScarsQinsButtonPushed(app, event)
            plat = app.PlatformIDEditField.Value;
            blanksText = app.BlanksIDsEditField.Value;
            blanksText = strrep(blanksText, " ", "");
            blanksList = strsplit(blanksText, ",");
            blanks = str2double(blanksList);
            scars_IDs(plat, blanks);
        end

        % Button pushed function: MeasurePlatformAnglesButton
        function MeasurePlatformAnglesButtonPushed(app, event)
            plat = app.PlatformIDEditField.Value;
            blanksText = app.BlanksIDsEditField.Value;
            blanksText = strrep(blanksText, " ", "");
            blanksList = strsplit(blanksText, ",");
            blanks = str2double(blanksList);
            segLen = app.SegmentsLengthEditField.Value;
            segNum = app.SegmentsNumberEditField.Value;
            useSPStable = app.SPStableCheckBox.Value;
            scar_loop_f(plat, blanks, segLen, segNum, useSPStable);
        end

        % Button pushed function: MeasureandDisplayPlatformAnglesButton
        function MeasureandDisplayPlatformAnglesButtonPushed(app, event)
            plat = app.PlatformIDEditField.Value;
            blanksText = app.BlanksIDsEditField.Value;
            blanksText = strrep(blanksText, " ", "");
            blanksList = strsplit(blanksText, ",");
            blanks = str2double(blanksList);
            segLen = app.SegmentsLengthEditField.Value;
            segNum = app.SegmentsNumberEditField.Value;
            useSPStable = app.SPStableCheckBox.Value;
            scar_loop(plat, blanks, segLen, segNum, useSPStable);
        end

        % Button pushed function: MeasurePlatformAnglesButtonFolder
        function MeasurePlatformAnglesButtonFolderPushed(app, event)
            segLen = app.SegmentsLengthEditFieldFolder.Value;
            segNum = app.SegmentsNumberEditFieldFolder.Value;
            runAngles = app.AnglesCheckBox.Value;
            runCurvature = app.CurvatureCheckBox.Value;
            runAxesRatio = app.AxesRatioCheckBox.Value;
            core_analysis_folder_2(segLen, segNum, runAngles, runCurvature, runAxesRatio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SingleFilePanel = uipanel(app.UIFigure);
            app.SingleFilePanel.Title = 'Single File';
            app.ConvertScarsQinsButton = uibutton(app.SingleFilePanel, 'push');
            app.ConvertScarsQinsButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertScarsQinsButtonPushed, true);
            app.ConvertScarsQinsButton.Tag = 'ConvertFile';
            app.ConvertScarsQinsButton.Text = 'Convert ScarsQins';
            app.SPStableCheckBox = uicheckbox(app.SingleFilePanel);
            app.SPStableCheckBox.Text = 'SPStable';
            app.MeasurePlatformAnglesButton = uibutton(app.SingleFilePanel, 'push');
            app.MeasurePlatformAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurePlatformAnglesButtonPushed, true);
            app.MeasurePlatformAnglesButton.Tag = 'ScarsLoopF';
            app.MeasurePlatformAnglesButton.Text = 'Measure Platform Angles';
            app.BlanksIDsEditFieldLabel = uilabel(app.SingleFilePanel);
            app.BlanksIDsEditFieldLabel.Text = 'Blanks IDs';
            app.BlanksIDsEditField = uieditfield(app.SingleFilePanel, 'text');
            app.BlanksIDsEditField.Tag = 'BlanksIDs';
            app.SegmentsLengthEditFieldLabel = uilabel(app.SingleFilePanel);
            app.SegmentsLengthEditFieldLabel.Text = 'Segments Length';
            app.SegmentsLengthEditField = uieditfield(app.SingleFilePanel, 'numeric');
            app.SegmentsNumberEditFieldLabel = uilabel(app.SingleFilePanel);
            app.SegmentsNumberEditFieldLabel.Text = 'Segments Number';
            app.SegmentsNumberEditField = uieditfield(app.SingleFilePanel, 'numeric');
            app.PlatformIDEditField_2Label = uilabel(app.SingleFilePanel);
            app.PlatformIDEditField_2Label.Text = 'Platform ID';
            app.PlatformIDEditField = uieditfield(app.SingleFilePanel, 'numeric');
            app.PlatformIDEditField.Tag = 'PlatID';
            app.MeasureandDisplayPlatformAnglesButton = uibutton(app.SingleFilePanel, 'push');
            app.MeasureandDisplayPlatformAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureandDisplayPlatformAnglesButtonPushed, true);
            app.MeasureandDisplayPlatformAnglesButton.Tag = 'ScarsLoop';
            app.MeasureandDisplayPlatformAnglesButton.Text = 'Measure and Display Platform Angles';
            app.FullDirectoryPanel = uipanel(app.UIFigure);
            app.FullDirectoryPanel.Title = 'Full Directory';
            app.MeasurePlatformAnglesButtonFolder = uibutton(app.FullDirectoryPanel, 'push');
            app.MeasurePlatformAnglesButtonFolder.ButtonPushedFcn = createCallbackFcn(app, @MeasurePlatformAnglesButtonFolderPushed, true);
            app.MeasurePlatformAnglesButtonFolder.Tag = 'ScarsLoopF';
            app.MeasurePlatformAnglesButtonFolder.Text = 'Run Calculations on Folder';
            app.SegmentsLengthEditField_2Label = uilabel(app.FullDirectoryPanel);
            app.SegmentsLengthEditField_2Label.Text = 'Segments Length';
            app.SegmentsLengthEditFieldFolder = uieditfield(app.FullDirectoryPanel, 'numeric');
            app.SegmentsNumberEditField_2Label = uilabel(app.FullDirectoryPanel);
            app.SegmentsNumberEditField_2Label.Text = 'Segments Number';
            app.SegmentsNumberEditFieldFolder = uieditfield(app.FullDirectoryPanel, 'numeric');
            app.IncludecalculationsLabel = uilabel(app.FullDirectoryPanel);
            app.IncludecalculationsLabel.Text = 'Include calculations:';
            app.AnglesCheckBox = uicheckbox(app.FullDirectoryPanel);
            app.AnglesCheckBox.Text = 'Angles';
            app.CurvatureCheckBox = uicheckbox(app.FullDirectoryPanel);
            app.CurvatureCheckBox.Text = 'Curvature';
            app.AxesRatioCheckBox = uicheckbox(app.FullDirectoryPanel);
            app.AxesRatioCheckBox.Text = 'Axes Ratio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoreAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end